classdef blackjack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Dealer1               matlab.ui.control.UIAxes
        Dealer2               matlab.ui.control.UIAxes
        User1                 matlab.ui.control.UIAxes
        User2                 matlab.ui.control.UIAxes
        User3                 matlab.ui.control.UIAxes
        Dealer3               matlab.ui.control.UIAxes
        User4                 matlab.ui.control.UIAxes
        Dealer4               matlab.ui.control.UIAxes
        StartButton           matlab.ui.control.Button
        HitButton             matlab.ui.control.Button
        StandButton           matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        RestartButton         matlab.ui.control.Button
        dealerEditFieldLabel  matlab.ui.control.Label
        dealerEditField       matlab.ui.control.NumericEditField
        userEditFieldLabel    matlab.ui.control.Label
        userEditField         matlab.ui.control.NumericEditField
        User12                matlab.ui.control.UIAxes
        User22                matlab.ui.control.UIAxes
        User32                matlab.ui.control.UIAxes
        User42                matlab.ui.control.UIAxes
        SplitButton           matlab.ui.control.Button
        user2EditFieldLabel   matlab.ui.control.Label
        user2EditField        matlab.ui.control.NumericEditField
        SplitHitButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        DECK = ["as","2s","3s","4s","5s","6s","7s","8s","9s","10s","js","qs","ks","ac","2c","3c","4c","5c","6c","7c","8c","9c","10c","jc","qc","kc","ah","2h","3h","4h","5h","6h","7h","8h","9h","10h","jh","qh","kh","ad","2d","3d","4d","5d","6d","7d","8d","9d","10d","jd","qd","kd","as","2s","3s","4s","5s","6s","7s","8s","9s","10s","js","qs","ks","ac","2c","3c","4c","5c","6c","7c","8c","9c","10c","jc","qc","kc","ah","2h","3h","4h","5h","6h","7h","8h","9h","10h","jh","qh","kh","ad","2d","3d","4d","5d","6d","7d","8d","9d","10d","jd","qd","kd","as","2s","3s","4s","5s","6s","7s","8s","9s","10s","js","qs","ks","ac","2c","3c","4c","5c","6c","7c","8c","9c","10c","jc","qc","kc","ah","2h","3h","4h","5h","6h","7h","8h","9h","10h","jh","qh","kh","ad","2d","3d","4d","5d","6d","7d","8d","9d","10d","jd","qd","kd"];
        deck = [];
        round = 0;
        usertotal = 0;
        dealertotal = 0;
        dealerturn = 0;
        ace = 0;
        usercard1;
        usercard2;
        usercard3;
        usercard4;
        usertotal2 = 0;
        splitpoints = 0;
        round2 = 0;
        usersplit = 0;
    end
    
    methods (Access = private)
        
        function results = run(app)
            
            %draws random card
            hand = randsample(app.deck, 1);
            
            index_card1 = ismember(app.deck, hand(1));
            app.deck(index_card1) = [];
            points = 0;
            point = [0];
            
            for i =1
            if hand(i) == "2c" || hand(i) == "2s" || hand(i) == "2h" || hand(i) == "2d"
                    point(i) = 2;
                    app.splitpoints = 2;
                    
                elseif hand(i) == "3c" || hand(i) == "3s" || hand(i) == "3h" || hand(i) == "2d"
                    point(i) = 3;
                    app.splitpoints = 3;
                        
                elseif hand(i) == "4c" || hand(i) == "4s" || hand(i) == "4h" || hand(i) == "4d"
                    point(i) = 4;
                    app.splitpoints = 4;
                        
                elseif hand(i) == "5c" || hand(i) == "5s" || hand(i) == "5h" || hand(i) == "5d"
                    point(i) = 5;
                    app.splitpoints = 5;
                        
                elseif hand(i) == "6c" || hand(i) == "6s" || hand(i) == "6h" || hand(i) == "6d"
                    point(i) = 6;
                    app.splitpoints = 6;
                        
                elseif hand(i) == "7c" || hand(i) == "7s" || hand(i) == "7h" || hand(i) == "7d"
                    point(i) = 7;
                    app.splitpoints = 7;
                        
                elseif hand(i) == "8c" || hand(i) == "8s" || hand(i) == "8h" || hand(i) == "8d"
                    point(i) = 8;
                    app.splitpoints = 8;
                        
                elseif hand(i) == "9c" || hand(i) == "9s" || hand(i) == "9h" || hand(i) == "9d"
                    point(i) = 9;
                    app.splitpoints = 9;
                        
                elseif hand(i) == "10c" || hand(i) == "10s" || hand(i) == "10h" || hand(i) == "10d"
                    point(i) = 10;
                    app.splitpoints = 10;
                        
                elseif hand(i) == "jc" || hand(i) == "js" || hand(i) == "jh" || hand(i) == "jd"
                    point(i) = 10;
                    app.splitpoints = 10;
                        
                elseif hand(i) == "qc" || hand(i) == "qs" || hand(i) == "qh" || hand(i) == "qd"
                    point(i) = 10;
                    app.splitpoints = 10;
                        
                elseif hand(i) == "kc" || hand(i) == "ks" || hand(i) == "kh" || hand(i) == "kd"
                    point(i) = 10;
                    app.splitpoints = 2;
                    
                    elseif hand(i) == "ac" || hand(i) == "as" || hand(i) == "ah" || hand(i) == "ad"
                    point(i) = 11;
                    app.ace = 1;
                    app.splitpoints = 11;
                        
                   
            end %for if
                 points = point(i) + points;
            end %for loop
            
              pic = [''];
            for i =1
                if hand(i) == "qs"
                    pic{i} = 'qs.png';
                    
                elseif hand(i) == "ks"
                    pic{i} = 'ks.png';
                    
                    elseif hand(i) == "js"
                    pic{i} = 'js.png';
                    
                    elseif hand(i) == "9s"
                    pic{i} = '9s.png';
                    
                    elseif hand(i) == "10s"
                    pic{i} = '10s.png';
                    
                    elseif hand(i) == "8s"
                    pic{i} = '8s.png';
                    
                    elseif hand(i) == "7s"
                    pic{i} = '7s.png';
                    
                    elseif hand(i) == "6s"
                    pic{i} = '6s.png';
                    
                    elseif hand(i) == "5s"
                    pic{i} = '5s.png';
                    
                    elseif hand(i) == "4s"
                    pic{i} = '4s.png';
                    
                    elseif hand(i) == "3s"
                    pic{i} = '3s.png';
                    
                    elseif hand(i) == "as"
                    pic{i} = 'as.png';


                    elseif hand(i) == "2s"
                    pic{i} = '2s.png';


                    elseif hand(i) == "ah"
                    pic{i} = 'ah.png';
                    
                    elseif hand(i) == "kh"
                    pic{i} = 'kh.png';
                    
                    elseif hand(i) == "qh"
                    pic{i} = 'qh.png';
                    
                    elseif hand(i) == "jhc"
                    pic{i} = 'jh.png';
                    
                    elseif hand(i) == "10h"
                    pic{i} = '10h.png';
                    
                    elseif hand(i) == "9h"
                    pic{i} = '9h.png';
                    
                    elseif hand(i) == "8h"
                    pic{i} = '8h.png';
                    
                    elseif hand(i) == "7h"
                    pic{i} = '7h.png';
                    
                    elseif hand(i) == "6h"
                    pic{i} = '6h.png';


                    elseif hand(i) == "5h"
                    pic{i} = '5h.png';
                    
                    elseif hand(i) == "4h"
                    pic{i} = '4h.png';
                    
                    elseif hand(i) == "3h"
                    pic{i} = '3h.png';
                    
                    elseif hand(i) == "2h"
                    pic{i} = '2h.png';
                    
                   
                    elseif hand(i) == "ac"
                    pic{i} = 'ac.png';
                    
                    elseif hand(i) == "kc"
                    pic{i} = 'kc.png';
                    
                    elseif hand(i) == "qc"
                    pic{i} = 'qc.png';
                    
                    elseif hand(i) == "jc"
                    pic{i} = 'jc.png';
                    
                    elseif hand(i) == "10c"
                    pic{i} = '10c.png';
                    
                    elseif hand(i) == "9c"
                    pic{i} = '9c.png';
                    
                    elseif hand(i) == "8c"
                    pic{i} = '8c.png';
                    
                    elseif hand(i) == "7c"
                    pic{i} = '7c.png';
                    
                    elseif hand(i) == "6c"
                    pic{i} = '6c.png';
                    
                    elseif hand(i) == "5c"
                    pic{i} = '5c.png';
                    
                    elseif hand(i) == "4c"
                    pic{i} = '4c.png';
                    
                    elseif hand(i) == "3c"
                    pic{i} = '3c.png';
                    
                    elseif hand(i) == "2c"
                    pic{i} = '2c.png';
                    
                    elseif hand(i) == "ac"
                    pic{i} = 'ac.png';
                    
                    elseif hand(i) == "kd"
                    pic{i} = 'kd.png';
                    
                    elseif hand(i) == "qd"
                    pic{i} = 'qd.png';
                    
                    elseif hand(i) == "jd"
                    pic{i} = 'jd.png';
                    
                    elseif hand(i) == "10d"
                    pic{i} = '10d.png';
                    
                    elseif hand(i) == "9d"
                    pic{i} = '9d.png';
                    
                    elseif hand(i) == "8d"
                    pic{i} = '8d.png';
                    
                    elseif hand(i) == "7d"
                    pic{i} = '7d.png';
                    
                    elseif hand(i) == "6d"
                    pic{i} = '6d.png';
                    
                    elseif hand(i) == "5d"
                    pic{i} = '5d.png';
                    
                    elseif hand(i) == "4d"
                    pic{i} = '4d.png';
                 
                    elseif hand(i) == "3d"
                    pic{i} = '3d.png';
                    
                    elseif hand(i) == "2d"
                    pic{i} = '2d.png';
                    
                    elseif hand(i) == "ad"
                    pic{i} = 'ad.png';
                end
            end
            if app.dealerturn == 0
            if app.round == 0
            img = imread(pic{1});
            imshow(img, 'Parent',app.User1)
            app.usercard1 = img;
            elseif app.round == 1
            img = imread(pic{1});
            imshow(img, 'Parent',app.User2)
            app.usercard2 = img;
            elseif app.round == 2
            img = imread(pic{1});
            imshow(img, 'Parent',app.User3)
            app.usercard3 = img;
            elseif app.round == 3
            img = imread(pic{1});
            imshow(img, 'Parent',app.User4)
            app.usercard4 = img;
            end
            
            else
               if app.round == 0
            img = imread(pic{1});
            imshow(img, 'Parent',app.Dealer1)
            elseif app.round == 1
            img = imread(pic{1});
            imshow(img, 'Parent',app.Dealer2)
            elseif app.round == 2
            img = imread(pic{1});
            imshow(img, 'Parent',app.Dealer3)
            elseif app.round == 3
            img = imread(pic{1});
            imshow(img, 'Parent',app.Dealer4)
            end 
            end
            app.round = app.round + 1;
            %checks to see if user is over
            if app.dealerturn == 0
            app.usertotal = points + app.usertotal;
            else
                
            app.dealertotal = app.dealertotal + points;
            end
            
            if app.usertotal >= 22
                
               if app.ace == 1  
                app.usertotal = app.usertotal - 10;
                app.ace = 0;
            
               end
           end
            
if app.dealertotal >= 22
                    
             if app.ace == 1   
                app.dealertotal = app.dealertotal - 10;
                app.ace = 0;
            
end
end
            app.dealerEditField.Value = app.dealertotal;
            app.userEditField.Value = app.usertotal;
            end
                
 %=============================================================================================================================================================
 %=============================================================================================================================================================
 %=============================================================================================================================================================
 %=============================================================================================================================================================
 %=============================================================================================================================================================
 %=============================================================================================================================================================
 %=============================================================================================================================================================
 %=============================================================================================================================================================
 %=============================================================================================================================================================
        function results = split(app)
             %draws random card
            hand = randsample(app.deck, 1);
            
            index_card1 = ismember(app.deck, hand(1));
            app.deck(index_card1) = [];
            points = 0;
            point = [0];
            
            for i =1
            if hand(i) == "2c" || hand(i) == "2s" || hand(i) == "2h" || hand(i) == "2d"
                    point(i) = 2;
                    
                elseif hand(i) == "3c" || hand(i) == "3s" || hand(i) == "3h" || hand(i) == "2d"
                    point(i) = 3;
                        
                elseif hand(i) == "4c" || hand(i) == "4s" || hand(i) == "4h" || hand(i) == "4d"
                    point(i) = 4;
                        
                elseif hand(i) == "5c" || hand(i) == "5s" || hand(i) == "5h" || hand(i) == "5d"
                    point(i) = 5;
                        
                elseif hand(i) == "6c" || hand(i) == "6s" || hand(i) == "6h" || hand(i) == "6d"
                    point(i) = 6;
                        
                elseif hand(i) == "7c" || hand(i) == "7s" || hand(i) == "7h" || hand(i) == "7d"
                    point(i) = 7;
                        
                elseif hand(i) == "8c" || hand(i) == "8s" || hand(i) == "8h" || hand(i) == "8d"
                    point(i) = 8;
                        
                elseif hand(i) == "9c" || hand(i) == "9s" || hand(i) == "9h" || hand(i) == "9d"
                    point(i) = 9;
                        
                elseif hand(i) == "10c" || hand(i) == "10s" || hand(i) == "10h" || hand(i) == "10d"
                    point(i) = 10;
                        
                elseif hand(i) == "jc" || hand(i) == "js" || hand(i) == "jh" || hand(i) == "jd"
                    point(i) = 10;
                        
                elseif hand(i) == "qc" || hand(i) == "qs" || hand(i) == "qh" || hand(i) == "qd"
                    point(i) = 10;
                        
                elseif hand(i) == "kc" || hand(i) == "ks" || hand(i) == "kh" || hand(i) == "kd"
                    point(i) = 10;
                    
                    elseif hand(i) == "ac" || hand(i) == "as" || hand(i) == "ah" || hand(i) == "ad"
                    point(i) = 11;
                    app.ace = 1;


            end %for if
                 points = point(i) + points;
            end %for loop
            
              pic = [''];
            for i =1
                if hand(i) == "qs"
                    pic{i} = 'qs.png';
                    
                elseif hand(i) == "ks"
                    pic{i} = 'ks.png';
                    
                    elseif hand(i) == "js"
                    pic{i} = 'js.png';
                    
                    elseif hand(i) == "9s"
                    pic{i} = '9s.png';
                    
                    elseif hand(i) == "10s"
                    pic{i} = '10s.png';
                    
                    elseif hand(i) == "8s"
                    pic{i} = '8s.png';
                    
                    elseif hand(i) == "7s"
                    pic{i} = '7s.png';
                    
                    elseif hand(i) == "6s"
                    pic{i} = '6s.png';
                    
                    elseif hand(i) == "5s"
                    pic{i} = '5s.png';
                    
                    elseif hand(i) == "4s"
                    pic{i} = '4s.png';
                    
                    elseif hand(i) == "3s"
                    pic{i} = '3s.png';
                    
                    elseif hand(i) == "as"
                    pic{i} = 'as.png';


                    elseif hand(i) == "2s"
                    pic{i} = '2s.png';


                    elseif hand(i) == "ah"
                    pic{i} = 'ah.png';
                    
                    elseif hand(i) == "kh"
                    pic{i} = 'kh.png';
                    
                    elseif hand(i) == "qh"
                    pic{i} = 'qh.png';
                    
                    elseif hand(i) == "jhc"
                    pic{i} = 'jh.png';
                    
                    elseif hand(i) == "10h"
                    pic{i} = '10h.png';
                    
                    elseif hand(i) == "9h"
                    pic{i} = '9h.png';
                    
                    elseif hand(i) == "8h"
                    pic{i} = '8h.png';
                    
                    elseif hand(i) == "7h"
                    pic{i} = '7h.png';
                    
                    elseif hand(i) == "6h"
                    pic{i} = '6h.png';


                    elseif hand(i) == "5h"
                    pic{i} = '5h.png';
                    
                    elseif hand(i) == "4h"
                    pic{i} = '4h.png';
                    
                    elseif hand(i) == "3h"
                    pic{i} = '3h.png';
                    
                    elseif hand(i) == "2h"
                    pic{i} = '2h.png';
                    
                   
                    elseif hand(i) == "ac"
                    pic{i} = 'ac.png';
                    
                    elseif hand(i) == "kc"
                    pic{i} = 'kc.png';
                    
                    elseif hand(i) == "qc"
                    pic{i} = 'qc.png';
                    
                    elseif hand(i) == "jc"
                    pic{i} = 'jc.png';
                    
                    elseif hand(i) == "10c"
                    pic{i} = '10c.png';
                    
                    elseif hand(i) == "9c"
                    pic{i} = '9c.png';
                    
                    elseif hand(i) == "8c"
                    pic{i} = '8c.png';
                    
                    elseif hand(i) == "7c"
                    pic{i} = '7c.png';
                    
                    elseif hand(i) == "6c"
                    pic{i} = '6c.png';
                    
                    elseif hand(i) == "5c"
                    pic{i} = '5c.png';
                    
                    elseif hand(i) == "4c"
                    pic{i} = '4c.png';
                    
                    elseif hand(i) == "3c"
                    pic{i} = '3c.png';
                    
                    elseif hand(i) == "2c"
                    pic{i} = '2c.png';
                    
                    elseif hand(i) == "ac"
                    pic{i} = 'ac.png';
                    
                    elseif hand(i) == "kd"
                    pic{i} = 'kd.png';
                    
                    elseif hand(i) == "qd"
                    pic{i} = 'qd.png';
                    
                    elseif hand(i) == "jd"
                    pic{i} = 'jd.png';
                    
                    elseif hand(i) == "10d"
                    pic{i} = '10d.png';
                    
                    elseif hand(i) == "9d"
                    pic{i} = '9d.png';
                    
                    elseif hand(i) == "8d"
                    pic{i} = '8d.png';
                    
                    elseif hand(i) == "7d"
                    pic{i} = '7d.png';
                    
                    elseif hand(i) == "6d"
                    pic{i} = '6d.png';
                    
                    elseif hand(i) == "5d"
                    pic{i} = '5d.png';
                    
                    elseif hand(i) == "4d"
                    pic{i} = '4d.png';
                 
                    elseif hand(i) == "3d"
                    pic{i} = '3d.png';
                    
                    elseif hand(i) == "2d"
                    pic{i} = '2d.png';
                    
                    elseif hand(i) == "ad"
                    pic{i} = 'ad.png';
                end
            end
            if app.dealerturn == 0
            if app.round2 == 0
            img = imread(pic{1});
            imshow(img, 'Parent',app.User1)
            app.usercard1 = img;
            elseif app.round2 == 1
            img = imread(pic{1});
            imshow(img, 'Parent',app.User22)
            app.usercard2 = img;
            elseif app.round2 == 2
            img = imread(pic{1});
            imshow(img, 'Parent',app.User32)
            app.usercard3 = img;
            elseif app.round2 == 3
            img = imread(pic{1});
            imshow(img, 'Parent',app.User42)
            app.usercard4 = img;
            end 
            end


            %checks to see if user is over
            if app.dealerturn == 0
            app.usertotal2 = points + app.usertotal2;
            
            end
            
            if app.usertotal >= 22
                
               if app.ace == 1  
                app.usertotal = app.usertotal - 10;
                app.ace = 0;
            
               end
           end


            app.user2EditField.Value = app.usertotal2;
            app.round2 = app.round2 + 1;
            
            
            end
                
            
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            img = imread("background.png");
                imshow(img, 'Parent' , app.User1)
                imshow(img, 'Parent' , app.User2)
                imshow(img, 'Parent' , app.User3)
                imshow(img, 'Parent' , app.User4)
                imshow(img, 'Parent' , app.User12)
                imshow(img, 'Parent' , app.User22)
                imshow(img, 'Parent' , app.User32)
                imshow(img, 'Parent' , app.User42)
                imshow(img, 'Parent' , app.Dealer1)
                imshow(img, 'Parent' , app.Dealer2)
                imshow(img, 'Parent' , app.Dealer3)
                imshow(img, 'Parent' , app.Dealer4)
                
                 set(app.StandButton, 'Enable', 'off')
                 set(app.HitButton, 'Enable', 'off')
                 set(app.RestartButton, 'Enable', 'off')
                 set(app.SplitButton, 'Enable', 'off')
                 set(app.SplitHitButton, 'Enable', 'off')
      
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           set(app.StartButton, 'Enable', 'off')
           set(app.StandButton, 'Enable', 'on')
           set(app.HitButton, 'Enable', 'on')
           set(app.SplitButton, 'Enable', 'on')
           app.deck = app.DECK;
           app.dealerturn = 1;
           run(app)
           img = imread("back.png");
           imshow(img, 'Parent' , app.Dealer2)
           app.dealerturn = 0;
           app.round = 0;
           app.usersplit = 0;
           run(app)
           run(app)
        
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            if app.usertotal < 21
                run(app)
            else
                f = msgbox("You Lost"); 
               set(app.HitButton, 'Enable', 'off')
                
            end
             if app.usertotal > 21
                 f = msgbox("You Lost");
                set(app.HitButton, 'Enable', 'off') 
             elseif app.usertotal == 21
                 f = msgbox("You Won");
                set(app.HitButton, 'Enable', 'off')
             end
            
            
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
           set(app.StandButton, 'Enable', 'off')
           set(app.HitButton, 'Enable', 'off')
           set(app.SplitButton, 'Enable', 'off')
           set(app.SplitHitButton, 'Enable', 'off')
            app.dealerturn = 1;
            app.round = 1;
            
           
          run(app)
            if app.dealertotal < 18
                run(app)
                  if app.dealertotal < 18
                   run(app)
                      if app.dealertotal > 21
                         f = msgbox("You Won");
                      elseif app.dealertotal > app.usertotal
                         f = msgbox("You Lost");
                      elseif app.usertotal > 21
                         f = msgbox("You Lost");
                      elseif app.dealertotal < app.usertotal
                         f = msgbox("You Won");    
                      end
                  elseif app.dealertotal > 21
                   f = msgbox("You Won");
                  elseif app.usertotal > 21
                         f = msgbox("You Lost");
                  elseif app.dealertotal > app.usertotal
                   f = msgbox("You Lost");
                  elseif app.dealertotal < app.usertotal
                   f = msgbox("You Won");    
            end
            elseif app.dealertotal > 21
                f = msgbox("You Won");
            elseif app.usertotal > 21
                         f = msgbox("You Lost");
            elseif app.dealertotal > app.usertotal
                f = msgbox("You Lost");
            elseif app.dealertotal <= app.usertotal
                f = msgbox("You Won");    
            end
               
            if app.usersplit == 1
            if app.dealertotal < 18
                
                  if app.dealertotal < 18
                   
                      if app.dealertotal > 21
                         f = msgbox("You Won");
                      elseif app.usertotal2 > 21
                         f = msgbox("You Lost");
                      elseif app.dealertotal > app.usertotal2
                         f = msgbox("You Lost");
                      elseif app.dealertotal < app.usertotal2
                         f = msgbox("You Won");    
                      end
                  elseif app.dealertotal > 21
                   f = msgbox("You Won");
                  elseif app.usertotal2 > 21
                         f = msgbox("You Lost");
                  elseif app.dealertotal > app.usertotal2
                   f = msgbox("You Lost");
                  elseif app.dealertotal < app.usertotal2
                   f = msgbox("You Won");    
            end
            elseif app.dealertotal > 21
                f = msgbox("You Won");
            elseif app.usertotal2 > 21
                         f = msgbox("You Lost");
            elseif app.dealertotal > app.usertotal2
                f = msgbox("You Lost");
            elseif app.dealertotal <= app.usertotal2
                f = msgbox("You Won");    
            end
            end
                
            app.usertotal2 = 0;
            app.usertotal = 0;
            app.dealertotal = 0;
                set(app.RestartButton, 'Enable', 'on')
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
             img = imread("background.png");
                imshow(img, 'Parent' , app.User1)
                imshow(img, 'Parent' , app.User2)
                imshow(img, 'Parent' , app.User3)
                imshow(img, 'Parent' , app.User4)
                imshow(img, 'Parent' , app.User12)
                imshow(img, 'Parent' , app.User22)
                imshow(img, 'Parent' , app.User32)
                imshow(img, 'Parent' , app.User42)
                imshow(img, 'Parent' , app.Dealer1)
                imshow(img, 'Parent' , app.Dealer2)
                imshow(img, 'Parent' , app.Dealer3)
                imshow(img, 'Parent' , app.Dealer4)
                
                set(app.SplitButton, 'Enable', 'off')
                 set(app.StandButton, 'Enable', 'off')
                 set(app.HitButton, 'Enable', 'off')
                 set(app.StartButton, 'Enable', 'on')
                 set(app.RestartButton, 'Enable', 'off')
                 set(app.SplitHitButton, 'Enable', 'off')
                  app.dealertotal = 0;
                  app.dealerturn = 0;
                  app.usertotal = 0;
                  app.usertotal2 = 0;
                  app.deck = app.DECK;
                  app.round = 0;
                  app.ace = 0;
                  app.usersplit = 0;
                  app.dealerEditField.Value = app.dealertotal;
            app.userEditField.Value = app.usertotal;
        
        end

        % Button pushed function: SplitButton
        function SplitButtonPushed(app, event)
           set(app.SplitButton, 'Enable', 'off')
           set(app.SplitHitButton, 'Enable', 'on')
           app.round = 1;
           app.round2 = 1;
           app.usersplit = 1;
           img = imread("background.png");
                imshow(img, 'Parent' , app.User2)
           imshow(app.usercard2, 'Parent' , app.User12)
           app.usertotal = app.usertotal - app.splitpoints;
           app.usertotal2 = app.usertotal2 + app.splitpoints;
           split(app)
           run(app)
        end

        % Button pushed function: SplitHitButton
        function SplitHitButtonPushed(app, event)
            if app.usertotal2 < 21
                split(app)
            else
                f = msgbox("You Lost"); 
                set(app.SplitHitButton, 'Enable', 'off')
                
            end
             if app.usertotal2 > 21
                 f = msgbox("Your Split Lost");
                 set(app.SplitHitButton, 'Enable', 'off')
               
             elseif app.usertotal2 == 21
                 f = msgbox("Your Split Won");
                 set(app.SplitHitButton, 'Enable', 'off')
               
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Dealer1 = uiaxes(app.UIFigure);
            title(app.Dealer1, '')
            xlabel(app.Dealer1, '')
            ylabel(app.Dealer1, '')
            app.Dealer2 = uiaxes(app.UIFigure);
            title(app.Dealer2, '')
            xlabel(app.Dealer2, '')
            ylabel(app.Dealer2, '')
            app.User1 = uiaxes(app.UIFigure);
            title(app.User1, '')
            xlabel(app.User1, '')
            ylabel(app.User1, '')
            app.User2 = uiaxes(app.UIFigure);
            title(app.User2, '')
            xlabel(app.User2, '')
            ylabel(app.User2, '')
            app.User3 = uiaxes(app.UIFigure);
            title(app.User3, '')
            xlabel(app.User3, '')
            ylabel(app.User3, '')
            app.Dealer3 = uiaxes(app.UIFigure);
            title(app.Dealer3, '')
            xlabel(app.Dealer3, '')
            ylabel(app.Dealer3, '')
            app.User4 = uiaxes(app.UIFigure);
            title(app.User4, '')
            xlabel(app.User4, '')
            ylabel(app.User4, '')
            app.Dealer4 = uiaxes(app.UIFigure);
            title(app.Dealer4, '')
            xlabel(app.Dealer4, '')
            ylabel(app.Dealer4, '')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Dealer'};
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.dealerEditFieldLabel = uilabel(app.UIFigure);
            app.dealerEditFieldLabel.Text = 'dealer';
            app.dealerEditField = uieditfield(app.UIFigure, 'numeric');
            app.userEditFieldLabel = uilabel(app.UIFigure);
            app.userEditFieldLabel.Text = 'user';
            app.userEditField = uieditfield(app.UIFigure, 'numeric');
            app.User12 = uiaxes(app.UIFigure);
            title(app.User12, '')
            xlabel(app.User12, '')
            ylabel(app.User12, '')
            app.User22 = uiaxes(app.UIFigure);
            title(app.User22, '')
            xlabel(app.User22, '')
            ylabel(app.User22, '')
            app.User32 = uiaxes(app.UIFigure);
            title(app.User32, '')
            xlabel(app.User32, '')
            ylabel(app.User32, '')
            app.User42 = uiaxes(app.UIFigure);
            title(app.User42, '')
            xlabel(app.User42, '')
            ylabel(app.User42, '')
            app.SplitButton = uibutton(app.UIFigure, 'push');
            app.SplitButton.ButtonPushedFcn = createCallbackFcn(app, @SplitButtonPushed, true);
            app.SplitButton.Text = 'Split';
            app.user2EditFieldLabel = uilabel(app.UIFigure);
            app.user2EditFieldLabel.Text = 'user2';
            app.user2EditField = uieditfield(app.UIFigure, 'numeric');
            app.SplitHitButton = uibutton(app.UIFigure, 'push');
            app.SplitHitButton.ButtonPushedFcn = createCallbackFcn(app, @SplitHitButtonPushed, true);
            app.SplitHitButton.Text = 'Split Hit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blackjack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end